classdef gameplay_public < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        XButton                matlab.ui.control.Button
        Image5                 matlab.ui.control.Image
        RollAgainButton_2      matlab.ui.control.Button
        Image4                 matlab.ui.control.Image
        Image3                 matlab.ui.control.Image
        RollAgainButton_3      matlab.ui.control.Button
        ScoreEditField_2       matlab.ui.control.NumericEditField
        ScoreEditField_2Label  matlab.ui.control.Label
        ScoreEditField         matlab.ui.control.NumericEditField
        ScoreEditFieldLabel    matlab.ui.control.Label
        Image2                 matlab.ui.control.Image
        Image                  matlab.ui.control.Image
        EndTurnButton_3        matlab.ui.control.Button
        EndTurnButton_2        matlab.ui.control.Button
        RollButton             matlab.ui.control.Button
        RollButton_2           matlab.ui.control.Button
        UITable2               matlab.ui.control.Table
        UITable                matlab.ui.control.Table
    end


    properties (Access = private)
        DialogApp                   % Dialog box app
        CurrentSize = 35;           % Surface sample size
        CurrentColormap = 'Parula'; % Colormap
    end

    methods (Access = public)
    
        function updateplot(app, sz, c)
            % Store inputs as properties
            app.CurrentSize = sz;
            app.CurrentColormap = c;
            
            % Update plot 
            Z = peaks(sz);
            surf(app.UIAxes,Z);
            colormap(app.UIAxes,c);
            
            % Re-enable the Plot Options button
            app.OptionsButton.Enable = 'on';
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
        set(app.Image,'visible','on');
        set(app.Image2,'visible','on');
        set(app.Image3,'visible','off');
        set(app.Image4,'visible','off');
        set(app.Image5,'visible','off');
        set(app.XButton,'visible','off');
        end

        % Callback function
        function OptionsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.OptionsButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp = DialogAppExample(app, app.CurrentSize, app.CurrentColormap);
        end

        % Close request function: UIFigure
        function MainAppCloseRequest(app, event)
            % Delete both apps
            delete(app.DialogApp)
            delete(app)
        end

        % Button pushed function: RollButton_2
        function RollButton_2Pushed(app, event)
          gameScore = randi(1,1, 12)
          
          gameScore = rand(gameScore)
            display(gameScore);
            app.ScoreEditField.Value = gameScore
             [y,Fs]=audioread("MANYDICE.wav")
            sound(y,Fs)

            set(app.Image3,'visible','off');
        set(app.Image4,'visible','on');
        end

        % Value changed function: ScoreEditField
        function ScoreEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: RollButton
        function RollButtonPushed(app, event)
            gameScore = randi(1,1, 12)
          
          gameScore = rand(gameScore)
            display(gameScore);
            app.ScoreEditField_2.Value = gameScore
             [y,Fs]=audioread("MANYDICE.wav")
            sound(y,Fs)

            set(app.Image3,'visible','on');
        set(app.Image4,'visible','off');
        end

        % Value changed function: ScoreEditField_2
        function ScoreEditField_2ValueChanged(app, event)
         
        end

        % Button pushed function: EndTurnButton_3
        function EndTurnButton_3Pushed(app, event)
            endgamescreen_exported
            
             [y,Fs]=audioread("endGame.wav")
            sound(y,Fs)
        end

        % Button pushed function: EndTurnButton_2
        function EndTurnButton_2Pushed(app, event)
            endgamescreen_exported

             [y,Fs]=audioread("endGame.wav")
            sound(y,Fs)
        end

        % Button pushed function: RollAgainButton_3
        function RollAgainButton_3Pushed(app, event)
          [y,Fs]=audioread("MANYDICE.wav")
           sound(y,Fs)

           set(app.Image3,'visible','off');
        set(app.Image4,'visible','on');
        end

        % Button pushed function: RollAgainButton_2
        function RollAgainButton_2Pushed(app, event)
           [y,Fs]=audioread("MANYDICE.wav")
            sound(y,Fs)

            set(app.Image3,'visible','on');
        set(app.Image4,'visible','off');
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            %Snake Eyes Image and close Button-Turn off
                     set(app.Image5,'visible','off');
                     set(app.XButton,'visible','off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Display Plot';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Round'; 'Score'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Round'; 'Score'};
            app.UITable2.RowName = {};
            app.RollButton_2 = uibutton(app.UIFigure, 'push');
            app.RollButton_2.ButtonPushedFcn = createCallbackFcn(app, @RollButton_2Pushed, true);
            app.RollButton_2.Text = 'Roll';
            app.RollButton = uibutton(app.UIFigure, 'push');
            app.RollButton.ButtonPushedFcn = createCallbackFcn(app, @RollButtonPushed, true);
            app.RollButton.Text = 'Roll';
            app.EndTurnButton_2 = uibutton(app.UIFigure, 'push');
            app.EndTurnButton_2.ButtonPushedFcn = createCallbackFcn(app, @EndTurnButton_2Pushed, true);
            app.EndTurnButton_2.Text = 'End Turn';
            app.EndTurnButton_3 = uibutton(app.UIFigure, 'push');
            app.EndTurnButton_3.ButtonPushedFcn = createCallbackFcn(app, @EndTurnButton_3Pushed, true);
            app.EndTurnButton_3.Text = 'End Turn';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'SnakeIcon2.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'SnakeIcon.jpg';
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score:';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScoreEditField.ValueChangedFcn = createCallbackFcn(app, @ScoreEditFieldValueChanged, true);
            app.ScoreEditField_2Label = uilabel(app.UIFigure);
            app.ScoreEditField_2Label.Text = 'Score:';
            app.ScoreEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ScoreEditField_2.ValueChangedFcn = createCallbackFcn(app, @ScoreEditField_2ValueChanged, true);
            app.RollAgainButton_3 = uibutton(app.UIFigure, 'push');
            app.RollAgainButton_3.ButtonPushedFcn = createCallbackFcn(app, @RollAgainButton_3Pushed, true);
            app.RollAgainButton_3.Text = 'Roll Again';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'dice_gif.gif';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'dice_gif.gif';
            app.RollAgainButton_2 = uibutton(app.UIFigure, 'push');
            app.RollAgainButton_2.ButtonPushedFcn = createCallbackFcn(app, @RollAgainButton_2Pushed, true);
            app.RollAgainButton_2.Text = 'Roll Again';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'SnakeEyesGif.gif';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gameplay_public(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end